classdef VentanaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout_Principal            matlab.ui.container.GridLayout
        Container_Principal             matlab.ui.container.Panel
        TabGroup_Principal              matlab.ui.container.TabGroup
        Tab_Inicio                      matlab.ui.container.Tab
        Panel_Inicio                    matlab.ui.container.Panel
        GridLayout_Inicio               matlab.ui.container.GridLayout
        Image_Presentacion              matlab.ui.control.Image
        HTML_Texto_Presentacion         matlab.ui.control.HTML
        Button_Iniciar_Presentacion     matlab.ui.control.Button
        Button_Vermas_Presentacion      matlab.ui.control.Button
        Label_Titulo_Presentacion       matlab.ui.control.Label
        Panel_PiePagina                 matlab.ui.container.Panel
        Button_VerMas                   matlab.ui.control.Button
        Label_TextoInformacion          matlab.ui.control.Label
        Tab_Teoria                      matlab.ui.container.Tab
        Panel_Teoria                    matlab.ui.container.Panel
        GridLayout_Teoria               matlab.ui.container.GridLayout
        Panel_TeoriaIndice              matlab.ui.container.Panel
        Label_TeoriaTitulo_Temario      matlab.ui.control.Label
        Tree_TeoriaTemario              matlab.ui.container.Tree
        Node_SistemaEcuacionesLineales  matlab.ui.container.TreeNode
        Node_MetodosDirectos            matlab.ui.container.TreeNode
        Node_Cramer                     matlab.ui.container.TreeNode
        Node_EliminacindeGauss          matlab.ui.container.TreeNode
        Node_GaussJordan                matlab.ui.container.TreeNode
        Node_DescomposicinLU            matlab.ui.container.TreeNode
        Node_Metodositerativos          matlab.ui.container.TreeNode
        Node_Jacobi                     matlab.ui.container.TreeNode
        Node_GaussSeidel                matlab.ui.container.TreeNode
        Node_GSconrelajacin             matlab.ui.container.TreeNode
        Node_SistemaEcuacionesNoLineales  matlab.ui.container.TreeNode
        Node_MetodoNewtonRaphson        matlab.ui.container.TreeNode
        Panel_TeoriaInteraccion         matlab.ui.container.Panel
        Button_EjemploGrafico           matlab.ui.control.Button
        Label_TeoriaTitulo_Interaccion  matlab.ui.control.Label
        Panel_TeoriaContenido           matlab.ui.container.Panel
        TabGroup_TeoriaContenido        matlab.ui.container.TabGroup
        Tab_MarcoTeorico                matlab.ui.container.Tab
        MarcoteoricoLabel               matlab.ui.control.Label
        Tab_Ejemplo                     matlab.ui.container.Tab
        EjemploLabel                    matlab.ui.control.Label
        Tab_MasInformacion              matlab.ui.container.Tab
        MasinfovLabel                   matlab.ui.control.Label
        Panel_TeoriaContenido_Secciones  matlab.ui.container.Panel
        Button_MarcoTeorico             matlab.ui.control.Button
        Button_Ejemplo                  matlab.ui.control.Button
        Button_MasInformacion           matlab.ui.control.Button
        Tab_Evaluacion                  matlab.ui.container.Tab
        Panel_Evaluacion                matlab.ui.container.Panel
        GridLayout_Evaluacion           matlab.ui.container.GridLayout
        Panel_PanelControlEvaluacion    matlab.ui.container.Panel
        GridLayout_NavegacionEvaluacion  matlab.ui.container.GridLayout
        Label_PanelControlTituloEvaluacion  matlab.ui.control.Label
        ButtonGroup_NavegacionCuestionario  matlab.ui.container.ButtonGroup
        Button_PreguntasDesactivadas    matlab.ui.control.ToggleButton
        Button_Pregunta01               matlab.ui.control.ToggleButton
        Button_Pregunta02               matlab.ui.control.ToggleButton
        Button_Pregunta03               matlab.ui.control.ToggleButton
        Button_Pregunta04               matlab.ui.control.ToggleButton
        Button_Pregunta05               matlab.ui.control.ToggleButton
        Button_Pregunta06               matlab.ui.control.ToggleButton
        Button_Pregunta07               matlab.ui.control.ToggleButton
        Button_Pregunta08               matlab.ui.control.ToggleButton
        Button_Pregunta09               matlab.ui.control.ToggleButton
        Button_Pregunta10               matlab.ui.control.ToggleButton
        Label_TextoNavegacionEvaluacion  matlab.ui.control.Label
        Panel_PanelPregunta             matlab.ui.container.Panel
        TabGroup_Evaluacion             matlab.ui.container.TabGroup
        Tab_IniciarEvaluacion           matlab.ui.container.Tab
        Panel_InicioEvaluacion          matlab.ui.container.Panel
        Button_IniciarEvaluacion        matlab.ui.control.Button
        IinicioPLabel                   matlab.ui.control.Label
        Tab_Pregunta01                  matlab.ui.container.Tab
        Pregunta1Label                  matlab.ui.control.Label
        Tab_Pregunta02                  matlab.ui.container.Tab
        Pregunta2Label                  matlab.ui.control.Label
        Tab_Pregunta03                  matlab.ui.container.Tab
        Pregunta3Label                  matlab.ui.control.Label
        Tab_Pregunta04                  matlab.ui.container.Tab
        Pregunta4Label                  matlab.ui.control.Label
        Tab_Pregunta05                  matlab.ui.container.Tab
        Pregunta5Label                  matlab.ui.control.Label
        Tab_Pregunta06                  matlab.ui.container.Tab
        Pregunta6Label                  matlab.ui.control.Label
        Tab_Pregunta07                  matlab.ui.container.Tab
        Pregunta7Label                  matlab.ui.control.Label
        Tab_Pregunta08                  matlab.ui.container.Tab
        Pregunta8Label                  matlab.ui.control.Label
        Tab_Pregunta09                  matlab.ui.container.Tab
        Pregunta9Label                  matlab.ui.control.Label
        Tab_Pregunta10                  matlab.ui.container.Tab
        Pregunta10Label                 matlab.ui.control.Label
        Tab_Resultado                   matlab.ui.container.Tab
        FinPLabel                       matlab.ui.control.Label
        Panel_PreguntaTitulo            matlab.ui.container.Panel
        Label_TextoTitulo               matlab.ui.control.Label
        Button_DesarrolloPregunta       matlab.ui.control.Button
        Panel_Nota                      matlab.ui.container.Panel
        Label_PanelNota                 matlab.ui.control.Label
        Label_NotaTexto                 matlab.ui.control.Label
        Tab_Calculadora                 matlab.ui.container.Tab
        Panel_Calculadora               matlab.ui.container.Panel
        GridLayout_Calculadora          matlab.ui.container.GridLayout
        Panel_Calculadora_Contenido     matlab.ui.container.Panel
        TabGroup_Calculadora            matlab.ui.container.TabGroup
        Tab_Calculadora_EcuacionesLineales  matlab.ui.container.Tab
        Panel_EcuacionesLineales        matlab.ui.container.Panel
        GridLayout_Calculadora_EcuacionesLineales  matlab.ui.container.GridLayout
        Panel_PanelSeleccionMetodos     matlab.ui.container.Panel
        Label_Calculadora_SeleccionTitulo  matlab.ui.control.Label
        Label_Calculadora_Desplegable   matlab.ui.control.Label
        MtodosLabel                     matlab.ui.control.Label
        Calculadora_DropDown_Metodos    matlab.ui.control.DropDown
        ButtonGroup_Calculadora_Metodos  matlab.ui.container.ButtonGroup
        Calculadora_Button_Cramer       matlab.ui.control.RadioButton
        Calculadora_Button_EliminacindeGauss  matlab.ui.control.RadioButton
        Calculadora_Button_GaussJordan  matlab.ui.control.RadioButton
        Calculadora_Button_DescomposicionLU  matlab.ui.control.RadioButton
        Calculadora_Button_Jacobi       matlab.ui.control.RadioButton
        Calculadora_Button_GaussSeidel  matlab.ui.control.RadioButton
        Calculadora_Button_GS_Relagacion  matlab.ui.control.RadioButton
        Panel_Calculadora_Formulario_EcLineales  matlab.ui.container.Panel
        Panel_Calculadora_SubidaArchivo  matlab.ui.container.Panel
        Button_Calculadora_SubirCVS_EcLineales  matlab.ui.control.Button
        Tab_EcuacionesNoLineales        matlab.ui.container.Tab
        Panel_EcuacionesNoLineales      matlab.ui.container.Panel
        GridLayout_Calculadora_EcuacionesNoLineales  matlab.ui.container.GridLayout
        Panel_PanelBotonesCalculadora   matlab.ui.container.Panel
        Label_BotonesCalculadoraTitulo  matlab.ui.control.Label
        Label_Texto_AccionesCalculadora  matlab.ui.control.Label
        Panel_GrupoBotones              matlab.ui.container.Panel
        ButtonGroup_Botones             matlab.ui.container.ButtonGroup
        Button_Oculto                   matlab.ui.control.ToggleButton
        Button_Cuadrado                 matlab.ui.control.ToggleButton
        Button_ElevarN                  matlab.ui.control.ToggleButton
        Button_Raiz                     matlab.ui.control.ToggleButton
        Button_Pi                       matlab.ui.control.ToggleButton
        Button_Secante                  matlab.ui.control.ToggleButton
        Button_Cosecante                matlab.ui.control.ToggleButton
        Button_Cotangente               matlab.ui.control.ToggleButton
        Button_Tangente                 matlab.ui.control.ToggleButton
        Button_Coseno                   matlab.ui.control.ToggleButton
        Button_Seno                     matlab.ui.control.ToggleButton
        Button_Epsilon                  matlab.ui.control.ToggleButton
        Button_LogaritmoNatural         matlab.ui.control.ToggleButton
        Label                           matlab.ui.control.Label
        EditField_Calculadora_Campo     matlab.ui.control.EditField
        Button_Calculadora_ActualizarCampo  matlab.ui.control.Button
        Panel_Calculadora_Formulario_EcNoLineales  matlab.ui.container.Panel
        Panel_Calculadora_SubidaArchivo_EcNoLineales  matlab.ui.container.Panel
        Button_Calculadora_SubirCVS_EcNoLineales  matlab.ui.control.Button
        Panel_BarraTipoEcuacion         matlab.ui.container.Panel
        Button_Calculadora_EcLineales   matlab.ui.control.Button
        Button_Calculadora_EcNoLineales  matlab.ui.control.Button
        Label_Titulo                    matlab.ui.control.Label
        Label_Tema                      matlab.ui.control.Label
        Panel_Principal                 matlab.ui.container.Panel
        Button_Teoria                   matlab.ui.control.Button
        Button_Evaluacion               matlab.ui.control.Button
        Button_Inicio                   matlab.ui.control.Button
        Button_Calculadora              matlab.ui.control.Button
    end

    
    properties (Access = private)
        %Paleta de colores
        Property_Color01=[0.0588 0.1882 0.3412]; % Azul Oscuro - Letras principal, Fondo alternativo
        Property_Color02=[0 0.3451 0.4784]; % Azul Claro
        Property_Color03=[0 0.5333 0.5686]; % Verde Agua
        Property_Color04=[0.9255 0.9255 0.9255]; % Blanco Frio - Fondo principal, Letras salternativas
        %Variables globales
        Property_BarraNavegacion_BotonSeleccionado=matlab.ui.control.Button;
        Property_TeoriaContenido_BotonSeleccionado=matlab.ui.control.Button;
        Property_CalculadoraContenido_BotonSeleccionado=matlab.ui.control.Button;
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Inicializar variables globales
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Inicio;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_MarcoTeorico;
            app.Property_CalculadoraContenido_BotonSeleccionado=app.Button_Calculadora_EcLineales;
            %Inicializar Temario
            app.Node_SistemaEcuacionesLineales.expand;
            app.Node_MetodosDirectos.expand;
            app.Node_Metodositerativos.expand;
            app.Node_SistemaEcuacionesNoLineales.expand;
        end

        % Button pushed function: Button_Inicio
        function ButtonPushed_Inicio(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Inicio.BackgroundColor = app.Property_Color04;
            app.Button_Inicio.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Inicio;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Inicio;  
        end

        % Button pushed function: Button_Teoria
        function ButtonPushed_Teoria(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Teoria.BackgroundColor = app.Property_Color04;
            app.Button_Teoria.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Teoria;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Teoria;  
        end

        % Button pushed function: Button_Evaluacion
        function ButtonPushed_Evaluacion(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Evaluacion.BackgroundColor = app.Property_Color04;
            app.Button_Evaluacion.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Evaluacion;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Evaluacion;  
        end

        % Button pushed function: Button_Calculadora
        function ButtonPushed_Calculadora(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Calculadora.BackgroundColor = app.Property_Color04;
            app.Button_Calculadora.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Calculadora;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Calculadora;  
        end

        % Button pushed function: Button_MarcoTeorico
        function Teoria_ButtonPushed_MarcoTeorico(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_MarcoTeorico.BackgroundColor = app.Property_Color04;
            app.Button_MarcoTeorico.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_MarcoTeorico;
            %Seleccionar pestaña
            app.TabGroup_TeoriaContenido.SelectedTab=app.Tab_MarcoTeorico; 
        end

        % Button pushed function: Button_Ejemplo
        function Teoria_ButtonPushed_Ejemplo(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Ejemplo.BackgroundColor = app.Property_Color04;
            app.Button_Ejemplo.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_Ejemplo;
            %Seleccionar pestaña
            app.TabGroup_TeoriaContenido.SelectedTab=app.Tab_Ejemplo;
        end

        % Button pushed function: Button_MasInformacion
        function Teoria_ButtonPushed_MasInformacion(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_MasInformacion.BackgroundColor = app.Property_Color04;
            app.Button_MasInformacion.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_MasInformacion;
            %Seleccionar pestaña
            app.TabGroup_TeoriaContenido.SelectedTab=app.Tab_MasInformacion;
        end

        % Button pushed function: Button_IniciarEvaluacion
        function Evaluacion_ButtonPushed_IniciarEvaluacion(app, event)
            app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta01;
            app.Button_DesarrolloPregunta.Visible = true;
            app.Button_DesarrolloPregunta.Text = "Siguiente Pregunta";
        end

        % Button pushed function: Button_DesarrolloPregunta
        function Evaluacion_ButtonPushed_DesarrolloPregunta(app, event)
            %Boton que permite la interacción en el cuestionario
            if app.Button_DesarrolloPregunta.Text == "Finalizar Revision" || app.Button_DesarrolloPregunta.Text == "Finalizar Evaluación"
                app.TabGroup_Evaluacion.SelectedTab = app.Tab_IniciarEvaluacion;
                app.Button_DesarrolloPregunta.Visible = false;
            else
                Variable_PaginaPregunta = app.TabGroup_Evaluacion.SelectedTab;
                switch Variable_PaginaPregunta
                    case app.Tab_Pregunta01
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta02;
                    case app.Tab_Pregunta02
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta03;
                    case app.Tab_Pregunta03
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta04;
                    case app.Tab_Pregunta04
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta05;
                    case app.Tab_Pregunta05
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta06;
                    case app.Tab_Pregunta06
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta07;
                    case app.Tab_Pregunta07
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta08;
                    case app.Tab_Pregunta08
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta09;
                    case app.Tab_Pregunta09
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Pregunta10;
                        app.Button_DesarrolloPregunta.Text = "Finalizar Cuestionario";
                    case app.Tab_Pregunta10
                        app.TabGroup_Evaluacion.SelectedTab = app.Tab_Resultado;
                        app.Button_DesarrolloPregunta.Text = "Finalizar Evaluación";
                end
            end
        end

        % Selection changed function: 
        % ButtonGroup_NavegacionCuestionario
        function Evaluacion_ButtonGroupSelectionChanged_NavegacionCuestionario(app, event)
            %Grupo de botones que permite la revision de las preguntas
            app.Button_DesarrolloPregunta.Visible = true;
            app.Button_DesarrolloPregunta.Text = "Finalizar Revision";
            %Funcion de boton seleccionado
            Variable_BotonSeleccionado = app.ButtonGroup_NavegacionCuestionario.SelectedObject;
            switch Variable_BotonSeleccionado
                case app.Button_Pregunta01
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta01; 
                case app.Button_Pregunta02
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta02; 
                case app.Button_Pregunta03
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta03;
                case app.Button_Pregunta04
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta04; 
                case app.Button_Pregunta05
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta05; 
                case app.Button_Pregunta06
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta06; 
                case app.Button_Pregunta07
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta07;
                case app.Button_Pregunta08
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta08; 
                case app.Button_Pregunta09
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta09; 
                case app.Button_Pregunta10
                    app.TabGroup_Evaluacion.SelectedTab=app.Tab_Pregunta10; 
            end
        end

        % Value changed function: Calculadora_DropDown_Metodos
        function Calculadora_DropDown_MetodosValueChanged(app, event)
            value = app.Calculadora_DropDown_Metodos.Value;
            if strcmp(value,'Directos')
              app.Calculadora_Button_Cramer.Visible = 'on';
              app.Calculadora_Button_EliminacindeGauss.Visible = 'on';
              app.Calculadora_Button_GaussJordan.Visible = 'on';
              app.Calculadora_Button_DescomposicionLU.Visible = 'on';
              app.Calculadora_Button_Jacobi.Visible = 'off';
              app.Calculadora_Button_GaussSeidel.Visible = 'off';
              app.Calculadora_Button_GS_Relagacion.Visible = 'off';
              app.Calculadora_Button_Cramer.Value = true;
            elseif strcmp(value,'Iterativos')
              app.Calculadora_Button_Cramer.Visible = 'off';
              app.Calculadora_Button_EliminacindeGauss.Visible = 'off';
              app.Calculadora_Button_GaussJordan.Visible = 'off';
              app.Calculadora_Button_DescomposicionLU.Visible = 'off';
              app.Calculadora_Button_Jacobi.Visible = 'on';
              app.Calculadora_Button_GaussSeidel.Visible = 'on';
              app.Calculadora_Button_GS_Relagacion.Visible = 'on';
              app.Calculadora_Button_Jacobi.Value = true;
            end
        end

        % Button pushed function: Button_Calculadora_EcLineales
        function Calculadora_ButtonPushed_EcLineales(app, event)
            %Modificar color
            app.Property_CalculadoraContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Calculadora_EcLineales.BackgroundColor = app.Property_Color04;
            app.Button_Calculadora_EcLineales.FontColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado=app.Button_Calculadora_EcLineales;
            %Seleccionar pestaña
            app.TabGroup_Calculadora.SelectedTab=app.Tab_Calculadora_EcuacionesLineales;
        end

        % Button pushed function: Button_Calculadora_EcNoLineales
        function Calculadora_ButtonPushed_EcNoLineales(app, event)
            %Modificar color
            app.Property_CalculadoraContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Calculadora_EcNoLineales.BackgroundColor = app.Property_Color04;
            app.Button_Calculadora_EcNoLineales.FontColor = app.Property_Color03;
            app.Property_CalculadoraContenido_BotonSeleccionado=app.Button_Calculadora_EcNoLineales;
            %Seleccionar pestaña
            app.TabGroup_Calculadora.SelectedTab=app.Tab_EcuacionesNoLineales;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_Principal = uigridlayout(app.UIFigure);
            app.Container_Principal = uipanel(app.GridLayout_Principal);
            app.TabGroup_Principal = uitabgroup(app.Container_Principal);
            app.Tab_Inicio = uitab(app.TabGroup_Principal);
            app.Tab_Inicio.Title = 'Inicio';
            app.Panel_Inicio = uipanel(app.Tab_Inicio);
            app.GridLayout_Inicio = uigridlayout(app.Panel_Inicio);
            app.Image_Presentacion = uiimage(app.GridLayout_Inicio);
            app.Image_Presentacion.ImageSource = 'imagenInicio.png';
            app.HTML_Texto_Presentacion = uihtml(app.GridLayout_Inicio);
            app.HTML_Texto_Presentacion.HTMLSource = '<div style="font-size: 15px;text-align: justify;font-weight:600;"><p style="margin:7%;color:#0f3057;">Bienvenido, esta aplicación tiene como objetivo poder enseñarte el sistema de ecuaciones de manera fácil, interactiva y practica, también puedes realizar operaciones grandes y complejas en nuestra calculadora, te invitamos a empezar con el manual o la teoria.</p></div>';
            app.Button_Iniciar_Presentacion = uibutton(app.GridLayout_Inicio, 'push');
            app.Button_Iniciar_Presentacion.Text = 'Iniciar';
            app.Button_Vermas_Presentacion = uibutton(app.GridLayout_Inicio, 'push');
            app.Button_Vermas_Presentacion.Text = 'Ver más';
            app.Label_Titulo_Presentacion = uilabel(app.GridLayout_Inicio);
            app.Label_Titulo_Presentacion.Text = 'PRESENTACIÓN';
            app.Panel_PiePagina = uipanel(app.Tab_Inicio);
            app.Button_VerMas = uibutton(app.Panel_PiePagina, 'push');
            app.Button_VerMas.Text = 'Quiénes somos';
            app.Label_TextoInformacion = uilabel(app.Panel_PiePagina);
            app.Label_TextoInformacion.Text = 'Información sobre el equipo de desarrollo';
            app.Tab_Teoria = uitab(app.TabGroup_Principal);
            app.Tab_Teoria.Title = 'Teoria';
            app.Panel_Teoria = uipanel(app.Tab_Teoria);
            app.GridLayout_Teoria = uigridlayout(app.Panel_Teoria);
            app.Panel_TeoriaIndice = uipanel(app.GridLayout_Teoria);
            app.Label_TeoriaTitulo_Temario = uilabel(app.Panel_TeoriaIndice);
            app.Label_TeoriaTitulo_Temario.Text = 'Temario';
            app.Tree_TeoriaTemario = uitree(app.Panel_TeoriaIndice);
            app.Node_SistemaEcuacionesLineales = uitreenode(app.Tree_TeoriaTemario);
            app.Node_SistemaEcuacionesLineales.Text = 'Sistema ecuaciones lineales';
            app.Node_MetodosDirectos = uitreenode(app.Node_SistemaEcuacionesLineales);
            app.Node_MetodosDirectos.Text = 'Métodos directos';
            app.Node_Cramer = uitreenode(app.Node_MetodosDirectos);
            app.Node_Cramer.Text = 'Cramer';
            app.Node_EliminacindeGauss = uitreenode(app.Node_MetodosDirectos);
            app.Node_EliminacindeGauss.Text = 'Eliminación de Gauss';
            app.Node_GaussJordan = uitreenode(app.Node_MetodosDirectos);
            app.Node_GaussJordan.Text = 'Gauss-Jordan';
            app.Node_DescomposicinLU = uitreenode(app.Node_MetodosDirectos);
            app.Node_DescomposicinLU.Text = 'Descomposición LU';
            app.Node_Metodositerativos = uitreenode(app.Node_SistemaEcuacionesLineales);
            app.Node_Metodositerativos.Text = 'Métodos iterativos';
            app.Node_Jacobi = uitreenode(app.Node_Metodositerativos);
            app.Node_Jacobi.Text = 'Jacobi';
            app.Node_GaussSeidel = uitreenode(app.Node_Metodositerativos);
            app.Node_GaussSeidel.Text = 'Gauss-Seidel';
            app.Node_GSconrelajacin = uitreenode(app.Node_Metodositerativos);
            app.Node_GSconrelajacin.Text = 'G-S con relajación';
            app.Node_SistemaEcuacionesNoLineales = uitreenode(app.Tree_TeoriaTemario);
            app.Node_SistemaEcuacionesNoLineales.Text = 'Sistema ecuaciones no lineales';
            app.Node_MetodoNewtonRaphson = uitreenode(app.Node_SistemaEcuacionesNoLineales);
            app.Node_MetodoNewtonRaphson.Text = 'Método Newton-Raphson';
            app.Panel_TeoriaInteraccion = uipanel(app.GridLayout_Teoria);
            app.Button_EjemploGrafico = uibutton(app.Panel_TeoriaInteraccion, 'push');
            app.Button_EjemploGrafico.Text = 'Ejemplo grafico';
            app.Label_TeoriaTitulo_Interaccion = uilabel(app.Panel_TeoriaInteraccion);
            app.Label_TeoriaTitulo_Interaccion.Text = 'Interacción';
            app.Panel_TeoriaContenido = uipanel(app.GridLayout_Teoria);
            app.TabGroup_TeoriaContenido = uitabgroup(app.Panel_TeoriaContenido);
            app.Tab_MarcoTeorico = uitab(app.TabGroup_TeoriaContenido);
            app.Tab_MarcoTeorico.Title = 'MarcoTeorico';
            app.MarcoteoricoLabel = uilabel(app.Tab_MarcoTeorico);
            app.MarcoteoricoLabel.Text = 'Marco teorico';
            app.Tab_Ejemplo = uitab(app.TabGroup_TeoriaContenido);
            app.Tab_Ejemplo.Title = 'Ejemplo';
            app.EjemploLabel = uilabel(app.Tab_Ejemplo);
            app.EjemploLabel.Text = 'Ejemplo';
            app.Tab_MasInformacion = uitab(app.TabGroup_TeoriaContenido);
            app.Tab_MasInformacion.Title = 'MasInformacion';
            app.MasinfovLabel = uilabel(app.Tab_MasInformacion);
            app.MasinfovLabel.Text = 'Mas info :v';
            app.Panel_TeoriaContenido_Secciones = uipanel(app.Panel_TeoriaContenido);
            app.Button_MarcoTeorico = uibutton(app.Panel_TeoriaContenido_Secciones, 'push');
            app.Button_MarcoTeorico.ButtonPushedFcn = createCallbackFcn(app, @Teoria_ButtonPushed_MarcoTeorico, true);
            app.Button_MarcoTeorico.Text = 'Marco Teórico';
            app.Button_Ejemplo = uibutton(app.Panel_TeoriaContenido_Secciones, 'push');
            app.Button_Ejemplo.ButtonPushedFcn = createCallbackFcn(app, @Teoria_ButtonPushed_Ejemplo, true);
            app.Button_Ejemplo.Text = 'Ejemplo';
            app.Button_MasInformacion = uibutton(app.Panel_TeoriaContenido_Secciones, 'push');
            app.Button_MasInformacion.ButtonPushedFcn = createCallbackFcn(app, @Teoria_ButtonPushed_MasInformacion, true);
            app.Button_MasInformacion.Text = 'Mas información';
            app.Tab_Evaluacion = uitab(app.TabGroup_Principal);
            app.Tab_Evaluacion.Title = 'Evaluacion';
            app.Panel_Evaluacion = uipanel(app.Tab_Evaluacion);
            app.GridLayout_Evaluacion = uigridlayout(app.Panel_Evaluacion);
            app.Panel_PanelControlEvaluacion = uipanel(app.GridLayout_Evaluacion);
            app.GridLayout_NavegacionEvaluacion = uigridlayout(app.Panel_PanelControlEvaluacion);
            app.Label_PanelControlTituloEvaluacion = uilabel(app.GridLayout_NavegacionEvaluacion);
            app.Label_PanelControlTituloEvaluacion.Text = 'Panel de control';
            app.ButtonGroup_NavegacionCuestionario = uibuttongroup(app.GridLayout_NavegacionEvaluacion);
            app.ButtonGroup_NavegacionCuestionario.SelectionChangedFcn = createCallbackFcn(app, @Evaluacion_ButtonGroupSelectionChanged_NavegacionCuestionario, true);
            app.Button_PreguntasDesactivadas = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_PreguntasDesactivadas.Visible = 'off';
            app.Button_PreguntasDesactivadas.Text = 'Button_PreguntasDesactivadas';
            app.Button_PreguntasDesactivadas.Value = true;
            app.Button_Pregunta01 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta01.Text = '1';
            app.Button_Pregunta02 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta02.Text = '2';
            app.Button_Pregunta03 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta03.Text = '3';
            app.Button_Pregunta04 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta04.Text = '4';
            app.Button_Pregunta05 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta05.Text = '5';
            app.Button_Pregunta06 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta06.Text = '6';
            app.Button_Pregunta07 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta07.Text = '7';
            app.Button_Pregunta08 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta08.Text = '8';
            app.Button_Pregunta09 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta09.Text = '9';
            app.Button_Pregunta10 = uitogglebutton(app.ButtonGroup_NavegacionCuestionario);
            app.Button_Pregunta10.Text = '10';
            app.Label_TextoNavegacionEvaluacion = uilabel(app.ButtonGroup_NavegacionCuestionario);
            app.Label_TextoNavegacionEvaluacion.Text = {'Navegación'; 'del cuestionario'; ''};
            app.Panel_PanelPregunta = uipanel(app.GridLayout_Evaluacion);
            app.TabGroup_Evaluacion = uitabgroup(app.Panel_PanelPregunta);
            app.Tab_IniciarEvaluacion = uitab(app.TabGroup_Evaluacion);
            app.Tab_IniciarEvaluacion.Title = 'Inicio';
            app.Panel_InicioEvaluacion = uipanel(app.Tab_IniciarEvaluacion);
            app.Button_IniciarEvaluacion = uibutton(app.Panel_InicioEvaluacion, 'push');
            app.Button_IniciarEvaluacion.ButtonPushedFcn = createCallbackFcn(app, @Evaluacion_ButtonPushed_IniciarEvaluacion, true);
            app.Button_IniciarEvaluacion.Text = 'Iniciar Evaluación';
            app.IinicioPLabel = uilabel(app.Panel_InicioEvaluacion);
            app.IinicioPLabel.Text = 'IinicioP';
            app.Tab_Pregunta01 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta01.Title = 'Pre1';
            app.Pregunta1Label = uilabel(app.Tab_Pregunta01);
            app.Pregunta1Label.Text = 'Pregunta1';
            app.Tab_Pregunta02 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta02.Title = 'Pre2';
            app.Pregunta2Label = uilabel(app.Tab_Pregunta02);
            app.Pregunta2Label.Text = 'Pregunta2';
            app.Tab_Pregunta03 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta03.Title = 'Pre3';
            app.Pregunta3Label = uilabel(app.Tab_Pregunta03);
            app.Pregunta3Label.Text = 'Pregunta3';
            app.Tab_Pregunta04 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta04.Title = 'Pre4';
            app.Pregunta4Label = uilabel(app.Tab_Pregunta04);
            app.Pregunta4Label.Text = 'Pregunta4';
            app.Tab_Pregunta05 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta05.Title = 'Pre5';
            app.Pregunta5Label = uilabel(app.Tab_Pregunta05);
            app.Pregunta5Label.Text = 'Pregunta5';
            app.Tab_Pregunta06 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta06.Title = 'Pre6';
            app.Pregunta6Label = uilabel(app.Tab_Pregunta06);
            app.Pregunta6Label.Text = 'Pregunta6';
            app.Tab_Pregunta07 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta07.Title = 'Pre7';
            app.Pregunta7Label = uilabel(app.Tab_Pregunta07);
            app.Pregunta7Label.Text = 'Pregunta7';
            app.Tab_Pregunta08 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta08.Title = 'Pre8';
            app.Pregunta8Label = uilabel(app.Tab_Pregunta08);
            app.Pregunta8Label.Text = 'Pregunta8';
            app.Tab_Pregunta09 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta09.Title = 'Pre9';
            app.Pregunta9Label = uilabel(app.Tab_Pregunta09);
            app.Pregunta9Label.Text = 'Pregunta9';
            app.Tab_Pregunta10 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta10.Title = 'Pre10';
            app.Pregunta10Label = uilabel(app.Tab_Pregunta10);
            app.Pregunta10Label.Text = 'Pregunta10';
            app.Tab_Resultado = uitab(app.TabGroup_Evaluacion);
            app.Tab_Resultado.Title = 'Resultado';
            app.FinPLabel = uilabel(app.Tab_Resultado);
            app.FinPLabel.Text = 'FinP';
            app.Panel_PreguntaTitulo = uipanel(app.Panel_PanelPregunta);
            app.Label_TextoTitulo = uilabel(app.Panel_PreguntaTitulo);
            app.Label_TextoTitulo.Text = 'Cuestionario';
            app.Button_DesarrolloPregunta = uibutton(app.Panel_PanelPregunta, 'push');
            app.Button_DesarrolloPregunta.ButtonPushedFcn = createCallbackFcn(app, @Evaluacion_ButtonPushed_DesarrolloPregunta, true);
            app.Button_DesarrolloPregunta.Visible = 'off';
            app.Button_DesarrolloPregunta.Text = 'Siguiente Pregunta';
            app.Panel_Nota = uipanel(app.GridLayout_Evaluacion);
            app.Label_PanelNota = uilabel(app.Panel_Nota);
            app.Label_PanelNota.Text = '      NOTA :';
            app.Label_NotaTexto = uilabel(app.Panel_Nota);
            app.Label_NotaTexto.Text = '0';
            app.Tab_Calculadora = uitab(app.TabGroup_Principal);
            app.Tab_Calculadora.Title = 'Calculadora';
            app.Panel_Calculadora = uipanel(app.Tab_Calculadora);
            app.GridLayout_Calculadora = uigridlayout(app.Panel_Calculadora);
            app.Panel_Calculadora_Contenido = uipanel(app.GridLayout_Calculadora);
            app.TabGroup_Calculadora = uitabgroup(app.Panel_Calculadora_Contenido);
            app.Tab_Calculadora_EcuacionesLineales = uitab(app.TabGroup_Calculadora);
            app.Tab_Calculadora_EcuacionesLineales.Title = 'EcuLineales';
            app.Panel_EcuacionesLineales = uipanel(app.Tab_Calculadora_EcuacionesLineales);
            app.GridLayout_Calculadora_EcuacionesLineales = uigridlayout(app.Panel_EcuacionesLineales);
            app.Panel_PanelSeleccionMetodos = uipanel(app.GridLayout_Calculadora_EcuacionesLineales);
            app.Label_Calculadora_SeleccionTitulo = uilabel(app.Panel_PanelSeleccionMetodos);
            app.Label_Calculadora_SeleccionTitulo.Text = 'Selección de métodos';
            app.Label_Calculadora_Desplegable = uilabel(app.Panel_PanelSeleccionMetodos);
            app.Label_Calculadora_Desplegable.Text = {''; ''};
            app.MtodosLabel = uilabel(app.Panel_PanelSeleccionMetodos);
            app.MtodosLabel.Text = 'Métodos:';
            app.Calculadora_DropDown_Metodos = uidropdown(app.Panel_PanelSeleccionMetodos);
            app.Calculadora_DropDown_Metodos.Items = {'Directos', 'Iterativos'};
            app.Calculadora_DropDown_Metodos.ValueChangedFcn = createCallbackFcn(app, @Calculadora_DropDown_MetodosValueChanged, true);
            app.Calculadora_DropDown_Metodos.Value = 'Directos';
            app.ButtonGroup_Calculadora_Metodos = uibuttongroup(app.Panel_PanelSeleccionMetodos);
            app.Calculadora_Button_Cramer = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_Cramer.Text = 'Cramer';
            app.Calculadora_Button_Cramer.Value = true;
            app.Calculadora_Button_EliminacindeGauss = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_EliminacindeGauss.Text = 'Eliminación de Gauss';
            app.Calculadora_Button_GaussJordan = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_GaussJordan.Text = 'Gauss-Jordan';
            app.Calculadora_Button_DescomposicionLU = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_DescomposicionLU.Text = 'Descomposición LU';
            app.Calculadora_Button_Jacobi = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_Jacobi.Visible = 'off';
            app.Calculadora_Button_Jacobi.Text = 'Jacobi';
            app.Calculadora_Button_GaussSeidel = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_GaussSeidel.Visible = 'off';
            app.Calculadora_Button_GaussSeidel.Text = 'Gauss-Seidel';
            app.Calculadora_Button_GS_Relagacion = uiradiobutton(app.ButtonGroup_Calculadora_Metodos);
            app.Calculadora_Button_GS_Relagacion.Visible = 'off';
            app.Calculadora_Button_GS_Relagacion.Text = 'G-S con Relagación';
            app.Panel_Calculadora_Formulario_EcLineales = uipanel(app.GridLayout_Calculadora_EcuacionesLineales);
            app.Panel_Calculadora_SubidaArchivo = uipanel(app.GridLayout_Calculadora_EcuacionesLineales);
            app.Button_Calculadora_SubirCVS_EcLineales = uibutton(app.Panel_Calculadora_SubidaArchivo, 'push');
            app.Button_Calculadora_SubirCVS_EcLineales.Text = 'Subir archivo Excel';
            app.Tab_EcuacionesNoLineales = uitab(app.TabGroup_Calculadora);
            app.Tab_EcuacionesNoLineales.Title = 'EcuNoLineales';
            app.Panel_EcuacionesNoLineales = uipanel(app.Tab_EcuacionesNoLineales);
            app.GridLayout_Calculadora_EcuacionesNoLineales = uigridlayout(app.Panel_EcuacionesNoLineales);
            app.Panel_PanelBotonesCalculadora = uipanel(app.GridLayout_Calculadora_EcuacionesNoLineales);
            app.Label_BotonesCalculadoraTitulo = uilabel(app.Panel_PanelBotonesCalculadora);
            app.Label_BotonesCalculadoraTitulo.Text = 'Método Newtom-Raptson';
            app.Label_Texto_AccionesCalculadora = uilabel(app.Panel_PanelBotonesCalculadora);
            app.Label_Texto_AccionesCalculadora.Text = 'Acciones calculadora';
            app.Panel_GrupoBotones = uipanel(app.Panel_PanelBotonesCalculadora);
            app.ButtonGroup_Botones = uibuttongroup(app.Panel_GrupoBotones);
            app.Button_Oculto = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Oculto.Visible = 'off';
            app.Button_Oculto.Text = 'Button';
            app.Button_Oculto.Value = true;
            app.Button_Cuadrado = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Cuadrado.Icon = 'Boton01.png';
            app.Button_Cuadrado.Text = '';
            app.Button_ElevarN = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_ElevarN.Icon = 'Boton02.png';
            app.Button_ElevarN.Text = '';
            app.Button_Raiz = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Raiz.Icon = 'Boton03.png';
            app.Button_Raiz.Text = '';
            app.Button_Pi = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Pi.Icon = 'Boton04.png';
            app.Button_Pi.Text = '';
            app.Button_Secante = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Secante.Icon = 'Boton05.png';
            app.Button_Secante.Text = '';
            app.Button_Cosecante = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Cosecante.Icon = 'Boton06.png';
            app.Button_Cosecante.Text = '';
            app.Button_Cotangente = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Cotangente.Icon = 'Boton07.png';
            app.Button_Cotangente.Text = '';
            app.Button_Tangente = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Tangente.Icon = 'Boton08.png';
            app.Button_Tangente.Text = '';
            app.Button_Coseno = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Coseno.Icon = 'Boton09.png';
            app.Button_Coseno.Text = '';
            app.Button_Seno = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Seno.Icon = 'Boton10.png';
            app.Button_Seno.Text = '';
            app.Button_Epsilon = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_Epsilon.Icon = 'Boton11.png';
            app.Button_Epsilon.Text = '';
            app.Button_LogaritmoNatural = uitogglebutton(app.ButtonGroup_Botones);
            app.Button_LogaritmoNatural.Icon = 'Boton12.png';
            app.Button_LogaritmoNatural.Text = '';
            app.Label = uilabel(app.Panel_PanelBotonesCalculadora);
            app.Label.Text = '_______';
            app.EditField_Calculadora_Campo = uieditfield(app.Panel_PanelBotonesCalculadora, 'text');
            app.Button_Calculadora_ActualizarCampo = uibutton(app.Panel_PanelBotonesCalculadora, 'push');
            app.Button_Calculadora_ActualizarCampo.Text = 'Actualizar campo';
            app.Panel_Calculadora_Formulario_EcNoLineales = uipanel(app.GridLayout_Calculadora_EcuacionesNoLineales);
            app.Panel_Calculadora_SubidaArchivo_EcNoLineales = uipanel(app.GridLayout_Calculadora_EcuacionesNoLineales);
            app.Button_Calculadora_SubirCVS_EcNoLineales = uibutton(app.Panel_Calculadora_SubidaArchivo_EcNoLineales, 'push');
            app.Button_Calculadora_SubirCVS_EcNoLineales.Text = 'Subir archivo Excel';
            app.Panel_BarraTipoEcuacion = uipanel(app.Panel_Calculadora_Contenido);
            app.Button_Calculadora_EcLineales = uibutton(app.Panel_BarraTipoEcuacion, 'push');
            app.Button_Calculadora_EcLineales.ButtonPushedFcn = createCallbackFcn(app, @Calculadora_ButtonPushed_EcLineales, true);
            app.Button_Calculadora_EcLineales.Text = 'Ecuaciones lineales';
            app.Button_Calculadora_EcNoLineales = uibutton(app.Panel_BarraTipoEcuacion, 'push');
            app.Button_Calculadora_EcNoLineales.ButtonPushedFcn = createCallbackFcn(app, @Calculadora_ButtonPushed_EcNoLineales, true);
            app.Button_Calculadora_EcNoLineales.Text = 'Ecuaciones no lineales';
            app.Label_Titulo = uilabel(app.Container_Principal);
            app.Label_Titulo.Text = 'SOFTWARE DE ENSEÑANZA';
            app.Label_Tema = uilabel(app.Container_Principal);
            app.Label_Tema.Text = 'Sistema de ecuaciones';
            app.Panel_Principal = uipanel(app.Container_Principal);
            app.Button_Teoria = uibutton(app.Panel_Principal, 'push');
            app.Button_Teoria.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Teoria, true);
            app.Button_Teoria.Text = 'Teoria';
            app.Button_Evaluacion = uibutton(app.Panel_Principal, 'push');
            app.Button_Evaluacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Evaluacion, true);
            app.Button_Evaluacion.Text = 'Evaluacion';
            app.Button_Inicio = uibutton(app.Panel_Principal, 'push');
            app.Button_Inicio.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Inicio, true);
            app.Button_Inicio.Text = 'Inicio';
            app.Button_Calculadora = uibutton(app.Panel_Principal, 'push');
            app.Button_Calculadora.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Calculadora, true);
            app.Button_Calculadora.Text = 'Calculadora';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end